classdef ControllerOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SaveButton               matlab.ui.control.Button
        ControllerValuesLabel    matlab.ui.control.Label
        InsulineparametersPanel  matlab.ui.container.Panel
        k0EditFieldLabel         matlab.ui.control.Label
        k0EditField              matlab.ui.control.NumericEditField
        k1EditFieldLabel         matlab.ui.control.Label
        k1EditField              matlab.ui.control.NumericEditField
        k2EditFieldLabel         matlab.ui.control.Label
        k2EditField              matlab.ui.control.NumericEditField
        Panel                    matlab.ui.container.Panel
        KpEditFieldLabel         matlab.ui.control.Label
        KpEditField              matlab.ui.control.NumericEditField
        ProportionalCheckBox     matlab.ui.control.CheckBox
        Panel_2                  matlab.ui.container.Panel
        IntegralCheckBox         matlab.ui.control.CheckBox
        TiEditFieldLabel         matlab.ui.control.Label
        TiEditField              matlab.ui.control.NumericEditField
        Panel_3                  matlab.ui.container.Panel
        DerivativeCheckBox       matlab.ui.control.CheckBox
        TdEditFieldLabel         matlab.ui.control.Label
        TdEditField              matlab.ui.control.NumericEditField
        TargetPanel              matlab.ui.container.Panel
        TargetEditField          matlab.ui.control.NumericEditField
        GammaPanel               matlab.ui.container.Panel
        GammaEditField           matlab.ui.control.NumericEditField
        CancelButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainSimulator; % Main app - simulator
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.k0EditField.Enable = false;
            app.MainSimulator = mainApp;
            app.KpEditField.Value = 0.74;
            app.TiEditField.Value = 450; % min DAY
            app.TdEditField.Value = 90; % min DAY
            app.GammaEditField.Value = 0.5;
            app.k1EditField.Value = 1.966308;
            app.k2EditField.Value = 0.966584;
            app.k0EditField.Value = 1 - app.k1EditField.Value + app.k2EditField.Value;
            app.TargetEditField.Value = 90; % mg/dl
            
            ProportionalCheckBoxValueChanged(app,false);
            IntegralCheckBoxValueChanged(app,false);
            DerivativeCheckBoxValueChanged(app,false);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            typeC = "";
            if app.ProportionalCheckBox.Value == true
                values.Kp = app.KpEditField.Value;
                typeC = strcat(typeC,"P");
            else
                values.Kp = 0;
            end
            if app.IntegralCheckBox.Value == true
                values.Ti = app.TiEditField.Value;
                typeC = strcat(typeC,"I");
            else
                values.Ti = 0;
            end
            if app.DerivativeCheckBox.Value == true
                values.Td = app.TdEditField.Value;
                typeC = strcat(typeC,"D");
            else
                values.Td = 0;
            end
            
            values.gamma = app.GammaEditField.Value;
            values.k0 = app.k0EditField.Value;
            values.k1 = app.k1EditField.Value;
            values.k2 = app.k2EditField.Value;
            values.target = app.TargetEditField.Value;
            values.type = typeC;
            updateController(app.MainSimulator, values);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            cancelController(app.MainSimulator);
            delete(app);
        end

        % Value changed function: ProportionalCheckBox
        function ProportionalCheckBoxValueChanged(app, event)
            value = app.ProportionalCheckBox.Value;
            if value == true
                app.KpEditField.Enable = true;
            else
                app.KpEditField.Enable = false;
            end
        end

        % Value changed function: IntegralCheckBox
        function IntegralCheckBoxValueChanged(app, event)
            value = app.IntegralCheckBox.Value;
            if value == true
                app.TiEditField.Enable = true;
            else
                app.TiEditField.Enable = false;
            end
        end

        % Value changed function: DerivativeCheckBox
        function DerivativeCheckBoxValueChanged(app, event)
            value = app.DerivativeCheckBox.Value;
            if value == true
                app.TdEditField.Enable = true;
            else
                app.TdEditField.Enable = false;
            end
        end

        % Value changed function: k1EditField
        function k1EditFieldValueChanged(app, event)
            k1 = app.k1EditField.Value;
            app.k0EditField.Value = 1 - k1 + app.k2EditField.Value;
        end

        % Value changed function: k2EditField
        function k2EditFieldValueChanged(app, event)
            k2 = app.k2EditField.Value;
            app.k0EditField.Value = 1 - app.k1EditField.Value + k2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ControllerValuesLabel = uilabel(app.UIFigure);
            app.ControllerValuesLabel.Text = 'Controller Values';
            app.InsulineparametersPanel = uipanel(app.UIFigure);
            app.InsulineparametersPanel.Title = 'Insuline parameters';
            app.k0EditFieldLabel = uilabel(app.InsulineparametersPanel);
            app.k0EditFieldLabel.Text = 'k0';
            app.k0EditField = uieditfield(app.InsulineparametersPanel, 'numeric');
            app.k1EditFieldLabel = uilabel(app.InsulineparametersPanel);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1EditField = uieditfield(app.InsulineparametersPanel, 'numeric');
            app.k1EditField.ValueChangedFcn = createCallbackFcn(app, @k1EditFieldValueChanged, true);
            app.k2EditFieldLabel = uilabel(app.InsulineparametersPanel);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2EditField = uieditfield(app.InsulineparametersPanel, 'numeric');
            app.k2EditField.ValueChangedFcn = createCallbackFcn(app, @k2EditFieldValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.KpEditFieldLabel = uilabel(app.Panel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.Panel, 'numeric');
            app.ProportionalCheckBox = uicheckbox(app.Panel);
            app.ProportionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProportionalCheckBoxValueChanged, true);
            app.ProportionalCheckBox.Text = 'Proportional?';
            app.Panel_2 = uipanel(app.UIFigure);
            app.IntegralCheckBox = uicheckbox(app.Panel_2);
            app.IntegralCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegralCheckBoxValueChanged, true);
            app.IntegralCheckBox.Text = 'Integral?';
            app.TiEditFieldLabel = uilabel(app.Panel_2);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.DerivativeCheckBox = uicheckbox(app.Panel_3);
            app.DerivativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DerivativeCheckBoxValueChanged, true);
            app.DerivativeCheckBox.Text = 'Derivative?';
            app.TdEditFieldLabel = uilabel(app.Panel_3);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.Panel_3, 'numeric');
            app.TargetPanel = uipanel(app.UIFigure);
            app.TargetPanel.Title = 'Target';
            app.TargetEditField = uieditfield(app.TargetPanel, 'numeric');
            app.GammaPanel = uipanel(app.UIFigure);
            app.GammaPanel.Title = 'Gamma';
            app.GammaEditField = uieditfield(app.GammaPanel, 'numeric');
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControllerOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end