classdef NewScenario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NewscenariofilenameEditFieldLabel  matlab.ui.control.Label
        NewscenariofilenameEditField  matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            
        end

        % Value changed function: NewscenariofilenameEditField
        function NewscenariofilenameEditFieldValueChanged(app, event)
            name = app.NewscenariofilenameEditField.Value;
            mapp = app.mainApp;
            scenarios = dir(fullfile(mapp.ScenarioPath, '*.mat'));
            extension = '.mat';
            if strcmp(name(end-3:end),extension) == 0
                % There is no extension to the name, add extension
                app.NewscenariofilenameEditField.Value = strcat(name,extension);
                name = app.NewscenariofilenameEditField.Value;
            end
            
            names = {scenarios.name}';
            if any(strcmp(names,name))
                % The name already exists
                app.NewscenariofilenameEditField.BackgroundColor = '#FF7270';
                fig = uifigure;
                uialert(fig,'File already exists','');
            else
                app.NewscenariofilenameEditField.BackgroundColor = 'white';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            updateScenarioName(app.mainApp,app.NewscenariofilenameEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewscenariofilenameEditFieldLabel = uilabel(app.UIFigure);
            app.NewscenariofilenameEditFieldLabel.Text = 'New scenario file name';
            app.NewscenariofilenameEditField = uieditfield(app.UIFigure, 'text');
            app.NewscenariofilenameEditField.ValueChangedFcn = createCallbackFcn(app, @NewscenariofilenameEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewScenario(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end