classdef SimuladorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        ClosedLoopCheckBox             matlab.ui.control.CheckBox
        InsulinunitsDropDownLabel      matlab.ui.control.Label
        InsulinunitsDropDown           matlab.ui.control.DropDown
        GlycemiaunitsDropDownLabel     matlab.ui.control.Label
        GlycemiaunitsDropDown          matlab.ui.control.DropDown
        CLPanel                        matlab.ui.container.Panel
        AddnewcontrollervaluesButton   matlab.ui.control.Button
        ControllertypeDropDownLabel    matlab.ui.control.Label
        ControllertypeDropDown         matlab.ui.control.DropDown
        ViewcontrollervaluesButton     matlab.ui.control.Button
        AddnewcontrollerButton         matlab.ui.control.Button
        ControllervaluesDropDownLabel  matlab.ui.control.Label
        ControllervaluesDropDown       matlab.ui.control.DropDown
        OLPanel                        matlab.ui.container.Panel
        basalSpinnerLabel              matlab.ui.control.Label
        basalSpinner                   matlab.ui.control.Spinner
        CRSpinnerLabel                 matlab.ui.control.Label
        CRSpinner                      matlab.ui.control.Spinner
        ScenarioTab                    matlab.ui.container.Tab
        ImportscenarioButton           matlab.ui.control.Button
        PatientsPanel                  matlab.ui.container.Panel
        AvailablepatientsListBoxLabel  matlab.ui.control.Label
        AvailablePatientsListBox       matlab.ui.control.ListBox
        AddpatientstosimulationButton  matlab.ui.control.Button
        ViewselectedpatientsButton     matlab.ui.control.Button
        MealsPanel                     matlab.ui.container.Panel
        MisestimationCheckBox          matlab.ui.control.CheckBox
        LowerboundSpinnerLabel         matlab.ui.control.Label
        LowerboundSpinner              matlab.ui.control.Spinner
        UpperboundSpinnerLabel         matlab.ui.control.Label
        UpperboundSpinner              matlab.ui.control.Spinner
        UITable                        matlab.ui.control.Table
        ScenarioSavingPanel            matlab.ui.container.Panel
        ModifyScenarioSavingCheckBox   matlab.ui.control.CheckBox
        SavescenarioCheckBox           matlab.ui.control.CheckBox
        ModifySavingPanel              matlab.ui.container.Panel
        PathnameEditField              matlab.ui.control.EditField
        BrowseScenarioPath             matlab.ui.control.Button
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        ChangePathLabel                matlab.ui.control.Label
        ImportscenariofomfileCheckBox  matlab.ui.control.CheckBox
        ResultsfileTab                 matlab.ui.container.Tab
        ResultsFilePanel               matlab.ui.container.Panel
        ResultsNameField               matlab.ui.control.EditField
        ResultsPathField               matlab.ui.control.EditField
        BrowsepathButton               matlab.ui.control.Button
        PathLabel                      matlab.ui.control.Label
        NameLabel                      matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        individualCVGA                 matlab.ui.control.CheckBox
        populationCGM                  matlab.ui.control.CheckBox
        individualCGM                  matlab.ui.control.CheckBox
        populationPump                 matlab.ui.control.CheckBox
        individualPump                 matlab.ui.control.CheckBox
        StandarizedCGMmetricsPanel     matlab.ui.container.Panel
        TimeblocksCheckBox             matlab.ui.control.CheckBox
        TimePanel                      matlab.ui.container.Panel
        block24h                       matlab.ui.control.CheckBox
        blockDay                       matlab.ui.control.CheckBox
        blockNight                     matlab.ui.control.CheckBox
        CGMPanel                       matlab.ui.container.Panel
        MeanGlucose                    matlab.ui.control.CheckBox
        GlycemicVar                    matlab.ui.control.CheckBox
        Time250                        matlab.ui.control.CheckBox
        Time70_180                     matlab.ui.control.CheckBox
        Time70                         matlab.ui.control.CheckBox
        Time54                         matlab.ui.control.CheckBox
        LBGI                           matlab.ui.control.CheckBox
        HBGI                           matlab.ui.control.CheckBox
        AreaCurve                      matlab.ui.control.CheckBox
        Time180                        matlab.ui.control.CheckBox
        populationCVGA                 matlab.ui.control.CheckBox
        ChangeResultsFileCheckBox      matlab.ui.control.CheckBox
        SaveresultsCheckBox            matlab.ui.control.CheckBox
        GraphicoutputCheckBox          matlab.ui.control.CheckBox
        NumericoutputCheckBox          matlab.ui.control.CheckBox
        HardwareTab                    matlab.ui.container.Tab
        CGMsensorCheckBox              matlab.ui.control.CheckBox
        CGMdrop                        matlab.ui.control.DropDown
        CSIICheckBox                   matlab.ui.control.CheckBox
        CSIIdrop                       matlab.ui.control.DropDown
        ConfigurationLabel             matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        PatientsValues; % Values of AvailablePatientsListBox patients
        SelectedPatients; % Values of selected patients (Handler)
        ScenarioPath; % Path where to save or load scenarios
        ScenarioName; % Name of scenario to save or load
        ScenarioValues; % Scenario values from file
        ScenarioLoaded; % To save if the scenario was loaded
        ControllerType; % Controller choosen
        ControllerCL; % Controller values file name if CL
        ControllersPath; % Path to controllers file
        ControllerValuesPath; % Path to the controller values
        SelectedControllerPath; % Path to selected controller
        ScriptsPath; % Path to scripts
        ImportedScenario; % Scenario file name if imported
        ResultsPath; % Path where to save results
        ResultsName; % Name of results file to save
        CRValue; % Value of CR
        BasalValue; % Value of basal
        LowerBound; % Value of lower bound
        UpperBound; % Value of upper bound
        CLVariables; % Struct with closed loop variables
        ShowResults; % Struct with results to show
        CVGApath; % Path to execute CVGA file
    end
    
    properties (Access = private)
        ControllerOptionsApp % Controller options dialog box app
        NewScenarioName % Dialog box app to enter new scenario name
    end
    
    methods (Access = public)
        
        function updateController(app, values)
            % Pre: values not empty
            % Post: controller values are actualized
            app.AddnewcontrollervaluesButton.Enable = true;
            app.CLVariables.k0 = values.k0;
            app.CLVariables.k1 = values.k1;
            app.CLVariables.k2 = values.k2;
            app.CLVariables.target = values.target;
            app.CLVariables.gamma = values.gamma;
            app.CLVariables.Kp = values.Kp;
            app.CLVariables.Ti = values.Ti;
            app.CLVariables.Td = values.Td;
            
            variables = struct2cell(app.CLVariables);
            [file,path] = uiputfile('*.csv','Save new controller');
            filename = fullfile(path,file);
            csvwrite(filename,variables);
            
            ControllerValuesList(app);
        end
        
        function updateScenarioName(app,name)
            % Pre: name not empty
            % Post: new scenario name saved
            app.ScenarioName = name;
            
        end
        
        function cancelController(app)
            % Pre: --
            % Post: Controller figure closed
            app.AddnewcontrollervaluesButton.Enable = true;
        end
    end
    
    methods (Access = private)
        
        function CGMoutput = ShowCGMMetrics(app,G,tipus)
            % Pre: G contains at least one value array and tipus not empty
            % Post: Chosen metrics computed and displayed on independent
            % figures
            G_lenght = size(G);
            for j=1:G_lenght(1)
                G_n = G(j,:);
                NumMetrics = [];
                CGMgraph = zeros(1,5);
                labels = {};
                % Compute metrics
                if app.Time250.Value == true
                    NumMetrics = [NumMetrics 250];
                    [hyper,t,hypo] = time_inrange(G_n,250,3000);
                    CGMgraph(1) = t;
                    labels = [labels {'Very High(>250 mg/dl)....'}];
                end
                if app.Time180.Value == true
                    NumMetrics = [NumMetrics 181];
                    labels = [labels 'High(181-250 mg/dl)....'];
                end
                if app.Time70_180.Value == true
                    NumMetrics = [NumMetrics 70 180];
                    labels = [labels 'Target Range(70-180 mg/dl)....'];
                end
                if app.Time70.Value == true
                    NumMetrics = [NumMetrics 69];
                    labels = [labels 'Low(54-69 mg/dl)....'];
                end
                if app.Time54.Value == true
                    NumMetrics = [NumMetrics 54];
                    if (find(G_n < 54))
                        [hyper,t,hypo] = time_inrange(G_n,0,54);
                        CGMgraph(end) = t;
                    end
                    labels = [labels 'Very Low(<54 mg/dl)....'];
                end
                
                if ~isempty(NumMetrics)
                    NumMetrics = sort(NumMetrics,'descend');
                    nSize = size(NumMetrics);
                    up = NumMetrics(1);
                    index = 2;
                    for i=2:nSize(2)
                        low = NumMetrics(i);
                        if ((up ~= 181) && (low ~= 180)) && ((up ~= 70) && (low ~=69))
                            [hyper,t,hypo] = time_inrange(G_n,low,up);
                            CGMgraph(index) = t;
                            index = index + 1;
                        end
                        up = low;
                    end
                    
                    % Save computed metrics
                    CGMoutput(j,:) = CGMgraph;
                    
                    % Prepare data to be displayed
                    mean_t = sum(nonzeros(CGMgraph));
                    if mean_t > 0
                        CGMgraph = CGMgraph/mean_t*100;
                    end
                    round_CGMgraph = cellstr(num2str(round(CGMgraph(:))));
                    
                    label_size = size(labels);
                    for i=1:label_size(2)
                        Time = duration(hours(CGMgraph(i)),'format','hh:mm');
                        strT = datestr(Time,'HH:MM');
                        labels(i) = strcat(labels(i),round_CGMgraph(i),'%....(',strT,'h)');
                    end
                    
                    % Display metrics
                    if strcmp(tipus,'i') == 1
                        fig = uifigure('Name',app.SelectedPatients{j});
                    else
                        fig = uifigure('Name',"Population CGM");
                    end
                    axs = uiaxes(fig,"XLim",[0 8]);
                    bar(axs,1,CGMgraph,'stacked');
                    legend(axs,labels);
                else
                    CGMoutput(j,:) = nan;
                end
            end
        end
        
        function ControllerValuesList(app)
            % Pre: --
            % Post: Available controller values files loaded
            controllersVFiles = dir(fullfile(app.ControllerValuesPath, '*.csv'));
            controllersVNames = {controllersVFiles.name}';
            app.ControllervaluesDropDown.Items = regexprep(controllersVNames, '.csv', '');
            app.ControllervaluesDropDown.Value = app.ControllervaluesDropDown.Items(1);
            app.ControllerCL = app.ControllervaluesDropDown.Value;
        end
        
        function ControllersList(app)
            % Pre: --
            % Post: Available controllers files loaded
            controllersFiles = dir(fullfile(app.ControllersPath, '*.m'));
            controllersNames = {controllersFiles.name}';
            app.ControllertypeDropDown.Items = regexprep(controllersNames, '.m', '');
            app.ControllertypeDropDown.Value = app.ControllertypeDropDown.Items(1);
            app.ControllerType = app.ControllertypeDropDown.Value;
        end
        
        function ReadValuesFromControllerFile(app,controller)
            % Pre: controller not empty and containing string
            % Post: controller values read and loaded to app
            controller_file = strcat(controller, '.csv');
            controllerValues = csvread(strcat(app.ControllerValuesPath,controller_file));
            app.CLVariables.k0 = controllerValues(1);
            app.CLVariables.k1 = controllerValues(2);
            app.CLVariables.k2 = controllerValues(3);
            app.CLVariables.target = controllerValues(4);
            app.CLVariables.gamma = controllerValues(5);
            app.CLVariables.Kp = controllerValues(6);
            app.CLVariables.Ti = controllerValues(7);
            app.CLVariables.Td = controllerValues(8);
            app.CLVariables.SG = controllerValues(9);
            app.CLVariables.Ip = controllerValues(10);
            app.CLVariables.I = controllerValues(11);
            app.CLVariables.P = controllerValues(12);
            app.CLVariables.D = controllerValues(13);
            app.CLVariables.PID = controllerValues(14);
            app.CLVariables.ID = controllerValues(15);
        end
        
        function SaveScenarioValues(app,controller)
            % Pre: controller containing execution data
            % Post: controller data saved to new file in specified path
            filename = fullfile(app.ScenarioPath,app.ScenarioName);
            m = matfile(filename,'Writable',true);
            
            % Save scenario values
            m.patients = app.SelectedPatients;
            m.controller = controller;
            if app.MisestimationCheckBox.Value == true
                m.MealMisestimation = true;
                m.LB = app.LowerBound;
                m.UB = app.UpperBound;
            else
                m.MealMisestimation = false;
            end
            if app.ClosedLoopCheckBox.Value == true
                m.CL = true;
                m.controllerName = app.ControllerCL;
            else
                m.CL = false;
            end
        end
        
        function output = MeanGlucoseCGM(app,controller,G,tipus)
            % Pre: controller contains execution data, G contains at least
            % one states array and tipus not empty
            % Post: mean glucose computed and displayed on new figure
            G_lenght = size(G);
            for j=1:G_lenght(1)
                hist_states = G(j,:);
                plot((1:controller.sim_time)./60,hist_states(:));hold on; 
                xlim([0 24]);
                limitsY = get(gca,'ylim');
                X = [0 0 controller.sim_time/60 controller.sim_time/60];
                Y = [70 180 180 70]; fill(X,Y,'g','FaceAlpha',0.15,'EdgeAlpha',0.1);
                xlabel('Time (h)'); ylabel('G (mg/dl)');
                output(j,:) = hist_states(:);
            end
            
            if strcmp(tipus,'i') == 1
                size_p = size(app.SelectedPatients);
                patients = [];
                for i=1:size_p(2)
                    patient = strrep(app.SelectedPatients(i),'_',' ');
                    patients = [patients patient];
                end
                legend(patients);
            else
                legend("Population mean glucose");
            end
            hold off;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize CL checkbox
            CLCheckBoxValueChanged(app,false);
            % Initialize available controllers
            app.ControllersPath = 'SimuladorT1DM\controllers\';
            app.ControllerValuesPath = 'SimuladorT1DM\controllers\Values\';
            app.ScriptsPath = 'SimuladorT1DM\scripts\';
            app.SelectedControllerPath = 'SimuladorT1DM\controllers\';
            ControllersList(app);
            ControllerValuesList(app);
            % Initialize default CL values
            app.CLVariables.k0=0;
            app.CLVariables.k1=0;
            app.CLVariables.k2=0;
            app.CLVariables.target=0;
            app.CLVariables.gamma=0;
            app.CLVariables.Kp=0;
            app.CLVariables.Ti=0;
            app.CLVariables.Td=0;
            app.CLVariables.SG=0;
            app.CLVariables.Ip=0;
            app.CLVariables.I=0;
            app.CLVariables.P=0;
            app.CLVariables.D=0;
            app.CLVariables.PID=0;
            app.CLVariables.ID=0;
            
            % Initialize Import scenario checkbox
            app.ScenarioLoaded = false;
            ImportScenarioCheckBoxValueChanged(app,false);
            % Initialize Patients
            patientsFiles = dir(fullfile('SimuladorT1DM\patients\', '*.csv'));
            patientsNames = {patientsFiles.name}';
            app.AvailablePatientsListBox.Items = regexprep(patientsNames,'.csv','');
            app.SelectedPatients = nan;
            % Initialize meal misestimation
            MealMisestimationCheckBoxValueChanged(app,false);
            % Initialize scenario saving configuration
            app.ScenarioPath = "SimuladorT1DM\scenarios\";
            scenarios = dir(fullfile(app.ScenarioPath, '*.mat'));
            if isempty(scenarios)
                app.ScenarioName = 'scenario1.mat';
            else
                scenarioNum = length(scenarios);
                app.ScenarioName = strcat('scenario', num2str(scenarioNum+1), '.mat');
            end
            SaveScenarioCheckBoxValueChanged(app,false);
            ModifyScenarioSavingCheckBoxValueChanged(app,false);
            
            % Initialize Results File Configuration chechbox
            ChangeresultsfileCheckBoxValueChanged(app,false);
            app.ResultsPath = "SimuladorT1DM\results\";
            results = dir(fullfile(app.ResultsPath, '*.mat'));
            if isempty(results)
                app.ResultsName = 'results1.mat';
            else
                resultNum = length(results);
                app.ResultsName = strcat('results', num2str(resultNum+1), '.mat');
            end
            
            % Initialize show results
            app.ShowResults.iCVGA = false;
            app.ShowResults.pCVGA = false;
            app.ShowResults.pCGM = false;
            app.ShowResults.iCGM = false;
            app.ShowResults.pPump = false;
            app.ShowResults.iPump = false;
            app.ShowResults.b24h = false;
            app.ShowResults.bDay = false;
            app.ShowResults.bNight = false;
            app.ShowResults.Glucose = false;
            app.ShowResults.Glycemic = false;
            app.ShowResults.t250 = false;
            app.ShowResults.t70_180 = false;
            app.ShowResults.t70 = false;
            app.ShowResults.t54 = false;
            app.ShowResults.LBGI = false;
            app.ShowResults.HBGI = false;
            app.ShowResults.Curve = false;
            app.ShowResults.t180 = false;
            
            % Inicializate results output
            GraphicoutputCheckBoxValueChanged(app,false);
            SaveresultsCheckBoxValueChanged(app,false);
            individualCVGAValueChanged(app,false);
            
            % Initialize Hardware checkboxes
            CGMsensorCheckBoxValueChanged(app,false);
            CSIICheckBoxValueChanged(app,false);
            
            % Initialize data table
            myData = {'Initial BG [mg/dl]' 0;
                        'Meal intake [gr]' 0;
                        'Meal time [hh]' 0;
                        'Meals duration [min]' 0;
                        'Simulation time [days]' 0
                        };
            app.UITable.Data = myData;
            app.UITable.ColumnName = ({});
        end

        % Value changed function: ClosedLoopCheckBox
        function CLCheckBoxValueChanged(app, event)
            value = app.ClosedLoopCheckBox.Value;
            CLpanel = app.CLPanel.Children;
            if value == true
                % If CL is checked
                set(CLpanel,'Enable',true);
            else
                % If CL is unchcked
                set(CLpanel,'Enable',false);
            end
        end

        % Value changed function: ImportscenariofomfileCheckBox
        function ImportScenarioCheckBoxValueChanged(app, event)
            value = app.ImportscenariofomfileCheckBox.Value;
            meals = app.MealsPanel.Children;
            saving = app.ModifySavingPanel.Children;
            
            if value == true
                % If Import scenario from file is enabled, disable tab
                app.ImportscenarioButton.Enable = true;
                app.AvailablePatientsListBox.Enable = false;
                app.AddpatientstosimulationButton.Enable = false;
                set(meals,'Enable','off');
                set(saving,'Enable',false);
                app.SavescenarioCheckBox.Enable = false;
                app.ModifyScenarioSavingCheckBox.Enable = false;
            else
                % If Import scenario from file is disabled, enable tab
                app.ImportscenarioButton.Enable = false;
                app.AvailablePatientsListBox.Enable = true;
                app.AddpatientstosimulationButton.Enable = true;
                set(meals,'Enable','on');
                app.SavescenarioCheckBox.Enable = true;
                MealMisestimationCheckBoxValueChanged(app,false);
            end
        end

        % Value changed function: SaveresultsCheckBox
        function SaveresultsCheckBoxValueChanged(app, event)
            value = app.SaveresultsCheckBox.Value;
            results = app.ResultsFilePanel.Children;
            if value == true
                % If Results file is enabled, enable path and name
                app.ChangeResultsFileCheckBox.Enable = true;
                app.ResultsPathField.Value = app.ResultsPath;
                app.ResultsNameField.Value = app.ResultsName;
                if app.ChangeResultsFileCheckBox.Value == true
                    set(results,'Enable',true);
                end
            else
                 % If Results file is disabled, disable path and name
                app.ChangeResultsFileCheckBox.Enable = false;
                set(results,'Enable',false);
            end
        end

        % Value changed function: ChangeResultsFileCheckBox
        function ChangeresultsfileCheckBoxValueChanged(app, event)
            value = app.ChangeResultsFileCheckBox.Value;
            results = app.ResultsFilePanel.Children;
            if value == true
                set(results,'Enable',true);
            else
                set(results,'Enable',false);
            end
        end

        % Value changed function: CGMsensorCheckBox
        function CGMsensorCheckBoxValueChanged(app, event)
            value = app.CGMsensorCheckBox.Value;
            if value == true
                % If CGM sensor is enabled, enable CGM drop down
                app.CGMdrop.Enable = true;
            else
                % If CGM sensor is disabled, disable CGM drop down
                app.CGMdrop.Enable = false;
            end
        end

        % Value changed function: CSIICheckBox
        function CSIICheckBoxValueChanged(app, event)
            value = app.CSIICheckBox.Value;
            if value == true
                % If CSII sensor is enabled, enable CSII drop down
                app.CSIIdrop.Enable = true;
            else
                % If CSII sensor is disabled, disable CSII drop down
                app.CSIIdrop.Enable = false;
            end
        end

        % Button pushed function: AddpatientstosimulationButton
        function AddPatientsButtonPushed(app, event)
            app.SelectedPatients = app.AvailablePatientsListBox.Value;
        end

        % Button pushed function: ViewselectedpatientsButton
        function ViewSelectedPatientsButtonPushed(app, event)
            % Get the Selected Patients Values
            app.PatientsValues = zeros(length(app.SelectedPatients),20);
            patients = strcat(app.SelectedPatients,'.csv');
            for i = 1:length(app.SelectedPatients)
                app.PatientsValues(i,:) = csvread(strcat("SimuladorT1DM\patients\",patients(i)));
            end
            
            % Plot the Selected Patients Names
            fig = uifigure;
            fig.Position(3:4) = [340 350];
            table_names = table(app.SelectedPatients','VariableNames',"Selected Patients");
            uitable(fig,'Data', table_names);
        end

        % Value changed function: SavescenarioCheckBox
        function SaveScenarioCheckBoxValueChanged(app, event)
            value = app.SavescenarioCheckBox.Value;
            saving = app.ModifySavingPanel.Children;
            if value == true
                % If save scenario, enable modify configuration
                app.ModifyScenarioSavingCheckBox.Enable = true;
                app.FilenameEditField.Value = app.ScenarioName;
                app.PathnameEditField.Value = app.ScenarioPath;
                if app.ModifyScenarioSavingCheckBox.Value == true
                    set(saving,'Enable',true);
                end
            else
                % If not save scenario, disable modify configuration
                app.ModifyScenarioSavingCheckBox.Enable = false;
                set(saving,'Enable',false);
            end
        end

        % Value changed function: ModifyScenarioSavingCheckBox
        function ModifyScenarioSavingCheckBoxValueChanged(app, event)
            value = app.ModifyScenarioSavingCheckBox.Value;
            saving = app.ModifySavingPanel.Children;
            if value == true
                % If scenario saving modified, enable fields
                set(saving,'Enable',true);
            else
                % If not scenario saving modified, disable fields
                set(saving,'Enable',false);
            end
        end

        % Value changed function: MisestimationCheckBox
        function MealMisestimationCheckBoxValueChanged(app, event)
            value = app.MisestimationCheckBox.Value;
            if value == true
                % If consider misestimation, enable bounds
                app.LowerboundSpinner.Enable = true;
                app.UpperboundSpinner.Enable = true;
            else
                % If don't consider misestimation, disable bounds
                app.LowerboundSpinner.Enable = false;
                app.UpperboundSpinner.Enable = false;
            end
        end

        % Callback function
        function ChecknameButtonPushed(app, event)
            scenarios = dir(fullfile(app.ScenarioPath, '*.mat'));
            name = app.FilenameEditField.Value;
            extension = '.mat';
            if strcmp(name(end-3:end),extension) == 0
                % There is no extension to the name, add extension
                app.FilenameEditField.Value = strcat(name,extension);
            end
            
            names = {scenarios.name}';
            if any(strcmp(names,name))
                % The name already exists
                app.FilenameEditField.BackgroundColor = '#FF7270';
                fig = uifigure;
                uialert(fig,'File already exists','');
            else
                app.FilenameEditField.BackgroundColor = 'white';
            end
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            scenarios = dir(fullfile(app.ScenarioPath, '*.mat'));
            name = app.FilenameEditField.Value;
            extension = '.mat';
            if strcmp(name(end-3:end),extension) == 0
                % There is no extension to the name, add extension
                app.FilenameEditField.Value = strcat(name,extension);
                name = app.FilenameEditField.Value;
            end
            
            names = {scenarios.name}';
            if any(strcmp(names,name))
                % The name already exists
                app.FilenameEditField.BackgroundColor = '#FF7270';
                fig = uifigure;
                uialert(fig,'File already exists','');
            else
                app.FilenameEditField.BackgroundColor = 'white';
            end
        end

        % Button pushed function: BrowseScenarioPath
        function BrowseScenarioPathButtonPushed(app, event)
            currentPath = app.PathnameEditField.Value;
            app.ScenarioPath = uigetdir(currentPath);
            app.PathnameEditField.Value = app.ScenarioPath;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.EditData;
            oldData = event.PreviousData;
            data = app.UITable.Data;
            if any(newData < 0)
                % Negative value entered, incorrect value
                fig = uifigure;
                uialert(fig,'Must be a positive value','');
                data(indices(1),indices(2)) = {oldData};
            else
                data(indices(1),indices(2)) = {newData};
            end
            app.UITable.Data = data;
        end

        % Button pushed function: AddnewcontrollervaluesButton
        function AddnewcontrollervaluesButtonPushed(app, event)
            % Open window to new controller values
            app.AddnewcontrollervaluesButton.Enable = false;
            app.ControllerOptionsApp = ControllerOptions(app);
            
        end

        % Value changed function: ControllertypeDropDown
        function ControllertypeDropDownValueChanged(app, event)
            controller = app.ControllertypeDropDown.Value;
            app.ControllerCL = controller;
        end

        % Button pushed function: AddnewcontrollerButton
        function AddnewcontrollerButtonPushed(app, event)
            [name, path] = uigetfile(app.ControllersPath);
            app.ControllerType = name;
            app.SelectedControllerPath = path;
        end

        % Value changed function: ControllervaluesDropDown
        function ControllervaluesDropDownValueChanged(app, event)
            controller = app.ControllervaluesDropDown.Value;
            app.ControllerCL = controller;
            
            % Read values from controller file
            ReadValuesFromControllerFile(app,controller);
        end

        % Button pushed function: ViewcontrollervaluesButton
        function ViewcontrollervaluesButtonPushed(app, event)
            % Open new window with read values
            controller = app.ControllerCL;
            controllerName = strcat(controller,' values');
            fig = uifigure('Name', controllerName);
            fig.Position(3:4) = [240 400];
            v_names = ["k0", "k1", "k2", "Target", "Gamma", "Kp", "Ti", "Td", "SG", "Ip", "I", "P", "D", "PID", "ID"]';
            variables = struct2cell(app.CLVariables);
            table_names = table(v_names,variables,'VariableNames',{'Name','Value'});
            uitable(fig,'Data', table_names, 'Position',[20 20 200 365]);
        end

        % Button pushed function: ImportscenarioButton
        function ImportscenarioButtonPushed(app, event)
            [name, path] = uigetfile("SimuladorT1DM\scenarios\");
            app.ImportedScenario = name;
            app.ScenarioName = name;
            app.ScenarioPath = path;
            filename = fullfile(app.ScenarioPath,app.ScenarioName);
            scenario = load(filename);
            
            % Save values
            app.SelectedPatients = scenario.patients;
            app.ScenarioValues = scenario.controller;
            app.BasalValue = scenario.controller.basal;
            app.CRValue = scenario.controller.CR;
            app.ControllerCL = scenario.controller.ControllerCL;
            
            % Show values
            app.basalSpinner.Value = app.BasalValue;
            app.CRSpinner.Value = app.CRValue;
            
            if scenario.CL == true
                app.ClosedLoopCheckBox.Value = true;
                CLCheckBoxValueChanged(app,true);
                app.ControllerCL = scenario.controllerName;
                ReadValuesFromControllerFile(app,app.ControllerCL);
                app.ControllervaluesDropDown.Value = app.ControllerCL;
            end
            
            data = app.UITable.Data;
            data(1,2) = {app.ScenarioValues.initial_BG};
            data(2,2) = {num2str(app.ScenarioValues.grams_cho)};
            data(3,2) = {num2str(app.ScenarioValues.temps_cho)};
            data(4,2) = {app.ScenarioValues.meal_time};
            data(5,2) = {app.ScenarioValues.sim_time};
            app.UITable.Data = data;
            
            if scenario.MealMisestimation == true
                app.MisestimationCheckBox.Value = true;
                app.LowerBound = scenario.LB;
                app.UpperBound = scenario.UB;
                app.LowerboundSpinner.Value = app.LowerBound;
                app.UpperboundSpinner.Value = app.UpperBound;
            end
            
            app.FilenameEditField.Value = app.ScenarioName;
            app.PathnameEditField.Value = app.ScenarioPath;
            app.ScenarioLoaded = true;
            
        end

        % Callback function
        function ResultsCheckNameButtonPushed(app, event)
            results = dir(fullfile(app.ResultsPath, '*.mat'));
            name = app.ResultsNameField.Value;
            extension = '.mat';
            if strcmp(name(end-3:end),extension) == 0
                % There is no extension to the name, add extension
                app.ResultsNameField.Value = strcat(name,extension);
            end
            
            names = {results.name}';
            if any(strcmp(names,name))
                % The name already exists
                app.ResultsNameField.BackgroundColor = '#FF7270';
                fig = uifigure;
                uialert(fig,'File already exists','');
            else
                app.ResultsNameField.BackgroundColor = 'white';
            end
        end

        % Button pushed function: BrowsepathButton
        function BrowsepathButtonPushed(app, event)
            currentPath = app.ResultsPathField.Value;
            app.ResultsPath = uigetdir(currentPath);
            app.ResultsPathField.Value = app.ResultsPath;
        end

        % Value changed function: CRSpinner
        function CRSpinnerValueChanged(app, event)
            value = app.CRSpinner.Value;
            app.CRValue = value;
        end

        % Value changed function: basalSpinner
        function basalSpinnerValueChanged(app, event)
            value = app.basalSpinner.Value;
            app.BasalValue = value;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq
        end

        % Value changed function: TimeblocksCheckBox
        function TimeblocksCheckBoxValueChanged(app, event)
            enable = app.TimeblocksCheckBox.Enable;
            value = app.TimeblocksCheckBox.Value;
            time = app.TimePanel.Children;
            if value == true
                if enable == true
                    set(time,'Enable',true);
                else
                    set(time,'Enable',false);
                end
            else
                set(time,'Enable',false);
            end
        end

        % Value changed function: individualCVGA
        function individualCVGAValueChanged(app, event)
            value = app.individualCVGA.Value;
            app.ShowResults.iCVGA = value;
        end

        % Value changed function: LowerboundSpinner
        function LowerboundSpinnerValueChanged(app, event)
            app.LowerBound = app.LowerboundSpinner.Value;
        end

        % Value changed function: UpperboundSpinner
        function UpperboundSpinnerValueChanged(app, event)
            app.UpperBound = app.UpperboundSpinner.Value;
        end

        % Value changed function: GraphicoutputCheckBox
        function GraphicoutputCheckBoxValueChanged(app, event)
            value = app.GraphicoutputCheckBox.Value;
            CGM = app.CGMPanel.Children;
            time = app.TimePanel.Children;
            if value == true
                app.individualCVGA.Enable = true;
                app.populationCVGA.Enable = true;
                app.populationCGM.Enable = true;
                app.individualCGM.Enable = true;
                app.populationPump.Enable = true;
                app.individualPump.Enable = true;
                set(CGM,'Enable',true);
                app.TimeblocksCheckBox.Enable = true;
                if app.TimeblocksCheckBox.Value == true
                    set(time,'Enable',true);
                end
            else
                app.individualCVGA.Enable = false;
                app.populationCVGA.Enable = false;
                app.populationCGM.Enable = false;
                app.individualCGM.Enable = false;
                app.populationPump.Enable = false;
                app.individualPump.Enable = false;
                set(CGM,'Enable',false);
                app.TimeblocksCheckBox.Enable = false;
                set(time,'Enable',false);
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%% CHECK VALUES %%%%%%%%%%%%%%%%%%%%
            % Check CR value
            if app.ClosedLoopCheckBox.Value == false && app.CRSpinner.Value == 0
                fig = uifigure;
                uialert(fig,"CR value must be bigger than 0.","CR error");
                return;
            end
            
            % Check basal value
            if app.basalSpinner.Value == 0
                fig = uifigure;
                uialert(fig,'basal value must be bigger than 0.','basal error');
                return;
            end
            
            % Check selected patients
            if isempty(app.SelectedPatients)
                fig = uifigure;
                uialert(fig,'At least one patient must be chosen to execute the simulation.','Patient error');
                return;
            end
            
            % Check table
            initial_BG = cell2mat(app.UITable.Data(1,2));
            if ~isnumeric(initial_BG)
                initial_BG = str2num(initial_BG); %#ok<ST2NM> 
            end
            grams_cho = cell2mat(app.UITable.Data(2,2));
            if ~isnumeric(grams_cho)
                grams_cho = str2num(grams_cho); %#ok<ST2NM> 
            end
            temps_cho = cell2mat(app.UITable.Data(3,2));
            if ~isnumeric(temps_cho)
                temps_cho = str2num(temps_cho); %#ok<ST2NM> 
            end
            meal_time = cell2mat(app.UITable.Data(4,2));
            if ~isnumeric(meal_time)
                meal_time = str2num(meal_time); %#ok<ST2NM> 
            end
            
            tSize = size(temps_cho);
            gSize = size(grams_cho);
            if tSize(2) ~= gSize(2)
                fig = uifigure;
                uialert(fig,'Meal intake must have the same lenght as Meal time.','Meal lenght error');
                return;
            end
            
            sim_time = cell2mat(app.UITable.Data(5,2));
            if ~isnumeric(sim_time)
                sim_time = str2num(sim_time); %#ok<ST2NM> 
            end
            
            
            %%%%%%%%%%%%%% SCENARIO AND SIMULATION %%%%%%%%%%%%%%
            % Get data from app or imported data
            if app.ImportscenariofomfileCheckBox.Value == true
                controller = app.ScenarioValues;
            end
            
            controller.basal = app.BasalValue;
            controller.CR = app.CRValue;
            controller.ControllerCL = app.ControllerCL;
            
            controller.initial_BG = initial_BG;
            controller.grams_cho = grams_cho;
            controller.temps_cho = temps_cho;
            controller.meal_time = meal_time;
            controller.sim_time = sim_time;
            
            % If scenario loaded
            if app.ScenarioLoaded == true
                fig = uifigure;
                do = "Do you want to overwrite the scenario saving file?";
                over = "Overwritting confirmation";
                selection = uiconfirm(fig,do,over,"Options",{'Yes','No'});
                if strcmp(selection,"Yes") == 1
                    SaveScenarioValues(app,controller);
                    delete(fig);
                else
                    delete(fig);
                    fig = uifigure;
                    do = "Do you want to save the scenario on a new file?";
                    title = "New file";
                    select = uiconfirm(fig,do,title,"Options",{'Yes','No'});
                    if strcmp(select,"Yes") == 1
                        delete(fig);
                        app.NewScenarioName = NewScenario(app);
                        waitfor(app.NewScenarioName);
                        SaveScenarioValues(app,controller);
                    else
                        delete(fig);
                    end
                end
            end
            
            % Check if we want to save the scenario
            if app.SavescenarioCheckBox.Value == true
                % Create mat file
                if app.ModifyScenarioSavingCheckBox.Value == true
                    app.ScenarioName = app.FilenameEditField.Value;
                end
                SaveScenarioValues(app,controller);
            end
            
            controller.sim_time = 24*60;

            if app.ClosedLoopCheckBox.Value == false
                controller.tipus = 'OL';
            else
                if strcmp(app.ControllerType,'PID')
                    controller.tipus = 'PID';
                    controller.k0 = app.CLVariables.k0;
                    controller.k1 = app.CLVariables.k1;
                    controller.k2 = app.CLVariables.k2;
                    controller.target = app.CLVariables.target;
                    controller.gamma = app.CLVariables.gamma;
                    controller.Kp = app.CLVariables.Kp;
                    controller.Ti = app.CLVariables.Ti;
                    controller.Td = app.CLVariables.Td;
                    controller.SG=zeros(controller.sim_time,1);
                    controller.Ip=zeros(controller.sim_time,1);
                    controller.I=zeros(controller.sim_time,1);
                    controller.P=zeros(controller.sim_time,1);
                    controller.D=zeros(controller.sim_time,1);
                    controller.PID=zeros(controller.sim_time,1);
                    controller.ID=zeros(controller.sim_time,1);
                end
            end
            
            patients_lenght = size(app.SelectedPatients);
            patients = zeros(patients_lenght(2),20);
            for i=1:patients_lenght(2)
                patient_file = strcat(app.SelectedPatients{i}, '.csv');
                controller.pacient = csvread(strcat('SimuladorT1DM\patients\',patient_file));
                patients(i,:) = controller.pacient;
                for day=1:sim_time
                    [controller,states] = mainCL(controller);
                    G(i,:) = states(:,9)*18;
                end
            end
            
            
            if app.GraphicoutputCheckBox.Value == true
                if app.ShowResults.iCGM == true
                    output.individualCGM = ShowCGMMetrics(app,G,'i');
                    hold off;
                    if app.ShowResults.Glucose == true
                        output.imeanGlucose = MeanGlucoseCGM(app,controller,G,'i');
                        hold off;
                    end
                    if app.ShowResults.iCVGA == true
                        icvga = CVGA(G);
                        output.iCVGA = icvga;
                    end
                end
                
                if app.ShowResults.pCGM == true
                    population = mean(G);
                    output.populationCGM = ShowCGMMetrics(app,population,'p');
                    hold off;
                    if app.ShowResults.Glucose == true
                        output.pmeanGlucose = MeanGlucoseCGM(app,controller,population,'p');
                        hold off;
                    end
                    if app.ShowResults.pCVGA == true
                        pcvga = CVGA(population);
                        output.pCVGA = pcvga;
                    end
                end
            end
            
            %%%%%%%%% RESULTS %%%%%%%%%%
            if app.SaveresultsCheckBox.Value == true
                if app.ChangeResultsFileCheckBox.Value == true
                    app.ResultsName = app.ResultsNameField.Value;
                    app.ResultsPath = app.ResultsPathField.Value;
                end
                filename = fullfile(app.ResultsPath,app.ResultsName);
                results = matfile(filename,'Writable',true);
                results.states = states;
                results.controller = controller;
                if app.GraphicoutputCheckBox.Value == true
                    results.output = output;
                end
            end
            
        end

        % Value changed function: populationCGM
        function populationCGMValueChanged(app, event)
            value = app.populationCGM.Value;
            app.ShowResults.pCGM = value;
        end

        % Value changed function: individualCGM
        function individualCGMValueChanged(app, event)
            value = app.individualCGM.Value;
            app.ShowResults.iCGM = value;
        end

        % Value changed function: populationPump
        function populationPumpValueChanged(app, event)
            value = app.populationPump.Value;
            app.ShowResults.pPump = value;
        end

        % Value changed function: individualPump
        function individualPumpValueChanged(app, event)
            value = app.individualPump.Value;
            app.ShowResults.iPump = value;
        end

        % Value changed function: Time250
        function Time250ValueChanged(app, event)
            value = app.Time250.Value;
            app.ShowResults.t250 = value;
        end

        % Value changed function: Time180
        function Time180ValueChanged(app, event)
            value = app.Time180.Value;
            app.ShowResults.t180 = value;
        end

        % Value changed function: Time70_180
        function Time70_180ValueChanged(app, event)
            value = app.Time70_180.Value;
            app.ShowResults.t70_180 = value;
        end

        % Value changed function: Time70
        function Time70ValueChanged(app, event)
            value = app.Time70.Value;
            app.ShowResults.t70 = value;
        end

        % Value changed function: Time54
        function Time54ValueChanged(app, event)
            value = app.Time54.Value;
            app.ShowResults.t54 = value;
        end

        % Value changed function: MeanGlucose
        function MeanGlucoseValueChanged(app, event)
            value = app.MeanGlucose.Value;
            app.ShowResults.Glucose = value;
        end

        % Value changed function: GlycemicVar
        function GlycemicVarValueChanged(app, event)
            value = app.GlycemicVar.Value;
            app.ShowResults.Glycemic = value;
        end

        % Value changed function: LBGI
        function LBGIValueChanged(app, event)
            value = app.LBGI.Value;
            app.ShowResults.LBGI = value;
        end

        % Value changed function: HBGI
        function HBGIValueChanged(app, event)
            value = app.HBGI.Value;
            app.ShowResults.HBGI = value;
        end

        % Value changed function: AreaCurve
        function AreaCurveValueChanged(app, event)
            value = app.AreaCurve.Value;
            app.ShowResults.Curve = value;
        end

        % Value changed function: block24h
        function block24hValueChanged(app, event)
            value = app.block24h.Value;
            app.ShowResults.b24h = value;
        end

        % Value changed function: blockDay
        function blockDayValueChanged(app, event)
            value = app.blockDay.Value;
            app.ShowResults.bDay = value;
        end

        % Value changed function: blockNight
        function blockNightValueChanged(app, event)
            value = app.blockNight.Value;
            app.ShowResults.bNight = value;
        end

        % Value changed function: ResultsNameField
        function ResultsNameFieldValueChanged(app, event)
            results = dir(fullfile(app.ResultsPath, '*.mat'));
            name = app.ResultsNameField.Value;
            extension = '.mat';
            if strcmp(name(end-3:end),extension) == 0
                % There is no extension to the name, add extension
                app.ResultsNameField.Value = strcat(name,extension);
                name = app.ResultsNameField.Value;
            end
            
            names = {results.name}';
            if any(strcmp(names,name))
                % The name already exists
                app.ResultsNameField.BackgroundColor = '#FF7270';
                fig = uifigure;
                uialert(fig,'File already exists','');
            else
                app.ResultsNameField.BackgroundColor = 'white';
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq;
        end

        % Value changed function: populationCVGA
        function populationCVGAValueChanged(app, event)
            value = app.populationCVGA.Value;
            app.ShowResults.iCVGA = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.ClosedLoopCheckBox = uicheckbox(app.SimulationTab);
            app.ClosedLoopCheckBox.ValueChangedFcn = createCallbackFcn(app, @CLCheckBoxValueChanged, true);
            app.ClosedLoopCheckBox.Text = 'Closed Loop?';
            app.InsulinunitsDropDownLabel = uilabel(app.SimulationTab);
            app.InsulinunitsDropDownLabel.Text = 'Insulin units';
            app.InsulinunitsDropDown = uidropdown(app.SimulationTab);
            app.InsulinunitsDropDown.Items = {'U/h', 'mmol/kg/min'};
            app.InsulinunitsDropDown.Value = 'U/h';
            app.GlycemiaunitsDropDownLabel = uilabel(app.SimulationTab);
            app.GlycemiaunitsDropDownLabel.Text = 'Glycemia units';
            app.GlycemiaunitsDropDown = uidropdown(app.SimulationTab);
            app.GlycemiaunitsDropDown.Items = {'mg/dl', 'mmol/L'};
            app.GlycemiaunitsDropDown.Value = 'mg/dl';
            app.CLPanel = uipanel(app.SimulationTab);
            app.AddnewcontrollervaluesButton = uibutton(app.CLPanel, 'push');
            app.AddnewcontrollervaluesButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewcontrollervaluesButtonPushed, true);
            app.AddnewcontrollervaluesButton.Text = 'Add new controller values';
            app.ControllertypeDropDownLabel = uilabel(app.CLPanel);
            app.ControllertypeDropDownLabel.Text = 'Controller type';
            app.ControllertypeDropDown = uidropdown(app.CLPanel);
            app.ControllertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllertypeDropDownValueChanged, true);
            app.ViewcontrollervaluesButton = uibutton(app.CLPanel, 'push');
            app.ViewcontrollervaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewcontrollervaluesButtonPushed, true);
            app.ViewcontrollervaluesButton.Text = 'View controller values';
            app.AddnewcontrollerButton = uibutton(app.CLPanel, 'push');
            app.AddnewcontrollerButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewcontrollerButtonPushed, true);
            app.AddnewcontrollerButton.Text = 'Add new controller';
            app.ControllervaluesDropDownLabel = uilabel(app.CLPanel);
            app.ControllervaluesDropDownLabel.Text = 'Controller values';
            app.ControllervaluesDropDown = uidropdown(app.CLPanel);
            app.ControllervaluesDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllervaluesDropDownValueChanged, true);
            app.OLPanel = uipanel(app.SimulationTab);
            app.basalSpinnerLabel = uilabel(app.OLPanel);
            app.basalSpinnerLabel.Text = 'basal';
            app.basalSpinner = uispinner(app.OLPanel);
            app.basalSpinner.Limits = [0 Inf];
            app.basalSpinner.ValueChangedFcn = createCallbackFcn(app, @basalSpinnerValueChanged, true);
            app.CRSpinnerLabel = uilabel(app.OLPanel);
            app.CRSpinnerLabel.Text = 'CR';
            app.CRSpinner = uispinner(app.OLPanel);
            app.CRSpinner.Limits = [0 Inf];
            app.CRSpinner.ValueChangedFcn = createCallbackFcn(app, @CRSpinnerValueChanged, true);
            app.ScenarioTab = uitab(app.TabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.ImportscenarioButton = uibutton(app.ScenarioTab, 'push');
            app.ImportscenarioButton.ButtonPushedFcn = createCallbackFcn(app, @ImportscenarioButtonPushed, true);
            app.ImportscenarioButton.Text = 'Import scenario';
            app.PatientsPanel = uipanel(app.ScenarioTab);
            app.AvailablepatientsListBoxLabel = uilabel(app.PatientsPanel);
            app.AvailablepatientsListBoxLabel.Text = 'Available patients';
            app.AvailablePatientsListBox = uilistbox(app.PatientsPanel);
            app.AvailablePatientsListBox.Items = {'Item 1', 'Item 5', 'Item 6', 'Item 7', 'Item 2', 'Item 3', 'Item 4'};
            app.AvailablePatientsListBox.Multiselect = 'on';
            app.AvailablePatientsListBox.Value = {'Item 5'};
            app.AddpatientstosimulationButton = uibutton(app.PatientsPanel, 'push');
            app.AddpatientstosimulationButton.ButtonPushedFcn = createCallbackFcn(app, @AddPatientsButtonPushed, true);
            app.AddpatientstosimulationButton.Text = 'Add patients to simulation';
            app.ViewselectedpatientsButton = uibutton(app.PatientsPanel, 'push');
            app.ViewselectedpatientsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSelectedPatientsButtonPushed, true);
            app.ViewselectedpatientsButton.Text = 'View selected patients';
            app.MealsPanel = uipanel(app.ScenarioTab);
            app.MisestimationCheckBox = uicheckbox(app.MealsPanel);
            app.MisestimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MealMisestimationCheckBoxValueChanged, true);
            app.MisestimationCheckBox.Text = 'Consider meal misestimation? [%]';
            app.LowerboundSpinnerLabel = uilabel(app.MealsPanel);
            app.LowerboundSpinnerLabel.Text = 'Lower bound';
            app.LowerboundSpinner = uispinner(app.MealsPanel);
            app.LowerboundSpinner.Limits = [0 Inf];
            app.LowerboundSpinner.ValueChangedFcn = createCallbackFcn(app, @LowerboundSpinnerValueChanged, true);
            app.UpperboundSpinnerLabel = uilabel(app.MealsPanel);
            app.UpperboundSpinnerLabel.Text = 'Upper bound';
            app.UpperboundSpinner = uispinner(app.MealsPanel);
            app.UpperboundSpinner.Limits = [0 Inf];
            app.UpperboundSpinner.ValueChangedFcn = createCallbackFcn(app, @UpperboundSpinnerValueChanged, true);
            app.UITable = uitable(app.MealsPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ScenarioSavingPanel = uipanel(app.ScenarioTab);
            app.ModifyScenarioSavingCheckBox = uicheckbox(app.ScenarioSavingPanel);
            app.ModifyScenarioSavingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModifyScenarioSavingCheckBoxValueChanged, true);
            app.ModifyScenarioSavingCheckBox.Text = 'Modify the scenario saving configuration?';
            app.SavescenarioCheckBox = uicheckbox(app.ScenarioSavingPanel);
            app.SavescenarioCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveScenarioCheckBoxValueChanged, true);
            app.SavescenarioCheckBox.Text = 'Save scenario?';
            app.ModifySavingPanel = uipanel(app.ScenarioSavingPanel);
            app.PathnameEditField = uieditfield(app.ModifySavingPanel, 'text');
            app.PathnameEditField.Editable = 'off';
            app.BrowseScenarioPath = uibutton(app.ModifySavingPanel, 'push');
            app.BrowseScenarioPath.ButtonPushedFcn = createCallbackFcn(app, @BrowseScenarioPathButtonPushed, true);
            app.BrowseScenarioPath.Text = 'Browse path';
            app.FilenameEditFieldLabel = uilabel(app.ModifySavingPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.ModifySavingPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.ChangePathLabel = uilabel(app.ModifySavingPanel);
            app.ChangePathLabel.Text = 'Change Path';
            app.ImportscenariofomfileCheckBox = uicheckbox(app.ScenarioTab);
            app.ImportscenariofomfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImportScenarioCheckBoxValueChanged, true);
            app.ImportscenariofomfileCheckBox.Text = 'Import scenario fom file?';
            app.ResultsfileTab = uitab(app.TabGroup);
            app.ResultsfileTab.Title = 'Results file';
            app.ResultsFilePanel = uipanel(app.ResultsfileTab);
            app.ResultsNameField = uieditfield(app.ResultsFilePanel, 'text');
            app.ResultsNameField.ValueChangedFcn = createCallbackFcn(app, @ResultsNameFieldValueChanged, true);
            app.ResultsPathField = uieditfield(app.ResultsFilePanel, 'text');
            app.ResultsPathField.Editable = 'off';
            app.BrowsepathButton = uibutton(app.ResultsFilePanel, 'push');
            app.BrowsepathButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsepathButtonPushed, true);
            app.BrowsepathButton.Text = 'Browse path';
            app.PathLabel = uilabel(app.ResultsFilePanel);
            app.PathLabel.Text = 'Path';
            app.NameLabel = uilabel(app.ResultsFilePanel);
            app.NameLabel.Text = 'Name';
            app.OutputPanel = uipanel(app.ResultsfileTab);
            app.OutputPanel.Title = 'Output';
            app.individualCVGA = uicheckbox(app.OutputPanel);
            app.individualCVGA.ValueChangedFcn = createCallbackFcn(app, @individualCVGAValueChanged, true);
            app.individualCVGA.Text = 'Show individual CVGA?';
            app.populationCGM = uicheckbox(app.OutputPanel);
            app.populationCGM.ValueChangedFcn = createCallbackFcn(app, @populationCGMValueChanged, true);
            app.populationCGM.Text = 'Show population CGM?';
            app.individualCGM = uicheckbox(app.OutputPanel);
            app.individualCGM.ValueChangedFcn = createCallbackFcn(app, @individualCGMValueChanged, true);
            app.individualCGM.Text = 'Show individual patient CGM?';
            app.populationPump = uicheckbox(app.OutputPanel);
            app.populationPump.ValueChangedFcn = createCallbackFcn(app, @populationPumpValueChanged, true);
            app.populationPump.Text = 'Show population pump?';
            app.individualPump = uicheckbox(app.OutputPanel);
            app.individualPump.ValueChangedFcn = createCallbackFcn(app, @individualPumpValueChanged, true);
            app.individualPump.Text = 'Show individual patient pump?';
            app.StandarizedCGMmetricsPanel = uipanel(app.OutputPanel);
            app.StandarizedCGMmetricsPanel.Title = 'Standarized CGM metrics';
            app.TimeblocksCheckBox = uicheckbox(app.StandarizedCGMmetricsPanel);
            app.TimeblocksCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeblocksCheckBoxValueChanged, true);
            app.TimeblocksCheckBox.Text = 'Time blocks?';
            app.TimePanel = uipanel(app.StandarizedCGMmetricsPanel);
            app.block24h = uicheckbox(app.TimePanel);
            app.block24h.ValueChangedFcn = createCallbackFcn(app, @block24hValueChanged, true);
            app.block24h.Text = '24-h blocks';
            app.blockDay = uicheckbox(app.TimePanel);
            app.blockDay.ValueChangedFcn = createCallbackFcn(app, @blockDayValueChanged, true);
            app.blockDay.Text = 'Day blocks';
            app.blockNight = uicheckbox(app.TimePanel);
            app.blockNight.ValueChangedFcn = createCallbackFcn(app, @blockNightValueChanged, true);
            app.blockNight.Text = 'Night blocks';
            app.CGMPanel = uipanel(app.StandarizedCGMmetricsPanel);
            app.MeanGlucose = uicheckbox(app.CGMPanel);
            app.MeanGlucose.ValueChangedFcn = createCallbackFcn(app, @MeanGlucoseValueChanged, true);
            app.MeanGlucose.Text = 'Mean glucose';
            app.GlycemicVar = uicheckbox(app.CGMPanel);
            app.GlycemicVar.ValueChangedFcn = createCallbackFcn(app, @GlycemicVarValueChanged, true);
            app.GlycemicVar.Text = 'Glycemic variability';
            app.Time250 = uicheckbox(app.CGMPanel);
            app.Time250.ValueChangedFcn = createCallbackFcn(app, @Time250ValueChanged, true);
            app.Time250.Text = 'Time > 250 mg/dL';
            app.Time70_180 = uicheckbox(app.CGMPanel);
            app.Time70_180.ValueChangedFcn = createCallbackFcn(app, @Time70_180ValueChanged, true);
            app.Time70_180.Text = 'Time 70-180 mg/dL';
            app.Time70 = uicheckbox(app.CGMPanel);
            app.Time70.ValueChangedFcn = createCallbackFcn(app, @Time70ValueChanged, true);
            app.Time70.Text = 'Time < 70 mg/dL';
            app.Time54 = uicheckbox(app.CGMPanel);
            app.Time54.ValueChangedFcn = createCallbackFcn(app, @Time54ValueChanged, true);
            app.Time54.Text = 'Time < 54 mg/dL';
            app.LBGI = uicheckbox(app.CGMPanel);
            app.LBGI.ValueChangedFcn = createCallbackFcn(app, @LBGIValueChanged, true);
            app.LBGI.Text = 'LBGI risk index';
            app.HBGI = uicheckbox(app.CGMPanel);
            app.HBGI.ValueChangedFcn = createCallbackFcn(app, @HBGIValueChanged, true);
            app.HBGI.Text = 'HBGI risk index';
            app.AreaCurve = uicheckbox(app.CGMPanel);
            app.AreaCurve.ValueChangedFcn = createCallbackFcn(app, @AreaCurveValueChanged, true);
            app.AreaCurve.Text = 'Area under the curve';
            app.Time180 = uicheckbox(app.CGMPanel);
            app.Time180.ValueChangedFcn = createCallbackFcn(app, @Time180ValueChanged, true);
            app.Time180.Text = 'Time > 180 mg/dL';
            app.populationCVGA = uicheckbox(app.OutputPanel);
            app.populationCVGA.ValueChangedFcn = createCallbackFcn(app, @populationCVGAValueChanged, true);
            app.populationCVGA.Text = 'Show population CVGA?';
            app.ChangeResultsFileCheckBox = uicheckbox(app.ResultsfileTab);
            app.ChangeResultsFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeresultsfileCheckBoxValueChanged, true);
            app.ChangeResultsFileCheckBox.Text = 'Change the results file configuration?';
            app.SaveresultsCheckBox = uicheckbox(app.ResultsfileTab);
            app.SaveresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveresultsCheckBoxValueChanged, true);
            app.SaveresultsCheckBox.Text = 'Save results?';
            app.GraphicoutputCheckBox = uicheckbox(app.ResultsfileTab);
            app.GraphicoutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphicoutputCheckBoxValueChanged, true);
            app.GraphicoutputCheckBox.Text = 'Graphic output?';
            app.NumericoutputCheckBox = uicheckbox(app.ResultsfileTab);
            app.NumericoutputCheckBox.Text = 'Numeric output?';
            app.HardwareTab = uitab(app.TabGroup);
            app.HardwareTab.Title = 'Hardware';
            app.CGMsensorCheckBox = uicheckbox(app.HardwareTab);
            app.CGMsensorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CGMsensorCheckBoxValueChanged, true);
            app.CGMsensorCheckBox.Text = 'Select CGM sensor?';
            app.CGMdrop = uidropdown(app.HardwareTab);
            app.CSIICheckBox = uicheckbox(app.HardwareTab);
            app.CSIICheckBox.ValueChangedFcn = createCallbackFcn(app, @CSIICheckBoxValueChanged, true);
            app.CSIICheckBox.Text = 'Select CSII?';
            app.CSIIdrop = uidropdown(app.HardwareTab);
            app.ConfigurationLabel = uilabel(app.UIFigure);
            app.ConfigurationLabel.Text = 'Configuration';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimuladorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end